classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        origin_appear_5  matlab.ui.control.Button
        origin_appear_4  matlab.ui.control.Button
        img_split        matlab.ui.control.Button
        img_located      matlab.ui.control.Button
        origin_appear    matlab.ui.control.Button
        UIAxes_2         matlab.ui.control.UIAxes
        UIAxes           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: origin_appear
        function origin_appearPushed(app, event)
            [file,path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif','Image Files(*.jpg, *.png, *.bmp, *.tif)'},'选择图片');
            if isequal(file,0)
                disp('用户取消了选择');
                return;
            end

            imgPath = fullfile(path,file);
            img = imread(imgPath);

            imshow(img,'Parent',app.UIAxes);
            title(app.UIAxes,'待检测图像显示');
        end

        % Button pushed function: img_located
        function img_locatedButtonPushed(app, event)
            imageObj = findobj(app.UIAxes,'Type','image');

            if isempty(imageObj)
                uialert(app.UIFigure,'UIAxes中没有图像','错误');
                return;
            end

            img = imageObj.CData;
            grayImg = rgb2gray(img);

            % 边缘检测增强轮廓
            edgeImg = edge(grayImg, 'Canny', [0.01 0.25], 1.2); % 降低sigma减少噪声
            
            % 形态学操作连接边缘
            % 使用水平方向细长结构元素（原[5 25]改为[3 15]）
            se_horizontal = strel('rectangle', [3 15]); 
            morphImg = imclose(edgeImg, se_horizontal);
            
            % 添加垂直方向开运算分离粘连区域
            se_vertical = strel('line', 5, 90);
            morphImg = imopen(morphImg, se_vertical);
            
            % 填充区域并去除小对象
            % 限制填充区域的最大面积（防止过度填充）
            filledImg = imfill(morphImg, 'holes');
            filledImg = bwareafilt(filledImg, [1000, 50000]); % 保留面积在1000-50000之间的区域
            
            figure;
            subplot(1,2,1); imshow(edgeImg); title('边缘检测');
            subplot(1,2,2); imshow(filledImg); title('填充后区域');
            
            
            % 定位候选区域
            stats = regionprops(filledImg, 'BoundingBox', 'Area', 'Extent', 'Solidity');
            fprintf('==== 候选区域统计信息 ====\n');
            for k = 1:length(stats)
                fprintf('区域 %d:\n', k);
                fprintf('  边界框 [x,y,width,height] = [%.1f, %.1f, %.1f, %.1f]\n', stats(k).BoundingBox);
                fprintf('  面积 Area = %.1f\n', stats(k).Area);
                fprintf('  完整性 Extent = %.2f\n', stats(k).Extent);
                fprintf('  紧密度 Solidity = %.2f\n', stats(k).Solidity);
                fprintf('--------------------------\n');
            end
            
            % 根据特征筛选车牌区域
            plateBox = [];
            for k = 1:length(stats)
                bb = stats(k).BoundingBox;
                aspectRatio = bb(3)/bb(4);
                
                fprintf('检查区域 %d: BoundingBox = [%.1f, %.1f, %.1f, %.1f]\n', k, bb);
                
                if aspectRatio > 0.5 && aspectRatio < 6 && ...
                   stats(k).Extent > 0.2 && bb(3)*bb(4) > 10000
                    plateBox = bb;
                    fprintf('>>> 找到候选车牌区域: [%.1f, %.1f, %.1f, %.1f]\n', bb);
                    break;
                end
            end
            
            % 提取车牌区域
            plateImg = imcrop(grayImg, plateBox);

            imshow(plateImg,'Parent',app.UIAxes_2);
            title(app.UIAxes_2,'车牌区域显示');
        end

        % Button pushed function: img_split
        function img_splitButtonPushed(app, event)
            imageObj = findobj(app.UIAxes_2,'Type','image');

            if isempty(imageObj)
                uialert(app.UIFigure,'UIAxes_2中没有图像','错误');
                return;
            end

            img = imageObj.CData;

            imshow(img,'Parent',app.UIAxes);
            title(app.UIAxes,'车牌区域显示');

            % 优化车牌图像
            plateBinary = imbinarize(img, 'adaptive', 'Sensitivity', 0.7);
            
            % 添加字符分离操作
            se = strel('rectangle', [1 3]);  % 细长水平结构元素
            plateClean = imopen(plateBinary, se);  % 开运算分离水平粘连
            
            % 去除小噪声（面积阈值降低到20）
            plateClean = bwareaopen(plateClean, 20);
            
            % 步骤3：倾斜校正
            charStatsTemp = regionprops(plateClean, 'Orientation');
            angles = [charStatsTemp.Orientation];
            medianAngle = median(angles);
            plateRotated = imrotate(plateClean, -medianAngle, 'bilinear', 'crop');
            
            % 步骤4：最终字符分割
            charStats = regionprops(plateRotated, 'BoundingBox', 'Image');
            
            
            % 动态宽度筛选
            allWidths = arrayfun(@(s) s.BoundingBox(3), charStats);
            if ~isempty(allWidths)
                medianWidth = median(allWidths);
                validIndices = find(allWidths > medianWidth*0.6 & ...
                                  allWidths < medianWidth*2);
                validChars = charStats(validIndices);
            else
                validChars = [];
            end
            
            % 按x坐标排序
            if ~isempty(validChars)
                xCoords = arrayfun(@(s) s.BoundingBox(1), validChars);
                [~, idx] = sort(xCoords);
                validChars = validChars(idx);
            end


            montage({validChars.Image}, 'Parent', app.UIAxes_2);
            title(app.UIAxes_2, '分割字符');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.origin_appear = uibutton(app.UIFigure, 'push');
            app.origin_appear.ButtonPushedFcn = createCallbackFcn(app, @origin_appearPushed, true);
            app.origin_appear.Text = '待检测图像显示';
            app.img_located = uibutton(app.UIFigure, 'push');
            app.img_located.ButtonPushedFcn = createCallbackFcn(app, @img_locatedButtonPushed, true);
            app.img_located.Text = '车牌区域定位';
            app.img_split = uibutton(app.UIFigure, 'push');
            app.img_split.ButtonPushedFcn = createCallbackFcn(app, @img_splitButtonPushed, true);
            app.img_split.Text = '车牌分割';
            app.origin_appear_4 = uibutton(app.UIFigure, 'push');
            app.origin_appear_4.Text = '训练分类器';
            app.origin_appear_5 = uibutton(app.UIFigure, 'push');
            app.origin_appear_5.Text = '车牌识别';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end